classdef AmplitudeAndPhaseSwapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AmplitudeAndPhaseSwapGUIUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Title                  matlab.ui.control.Label
        Copyrights             matlab.ui.control.Label
        ClearButton            matlab.ui.control.Button
        ApplyButton            matlab.ui.control.Button
        FullPathofImage2Label  matlab.ui.control.Label
        Imagepath1             matlab.ui.control.EditField
        FullPathofImage1Label  matlab.ui.control.Label
        Imagepath2             matlab.ui.control.EditField
        ResetButton            matlab.ui.control.Button
        Browse1                matlab.ui.control.Button
        Browse2                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            AmplitudeAndPhaseSwap(app.Imagepath1.Value,app.Imagepath2.Value)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Imagepath1.Value="moon.tif";
            app.Imagepath2.Value="cameraman.tif";
        end

        % Button pushed function: Browse1
        function Browse1Pushed(app, event)
            [file,path]=uigetfile({'*.jpg;*.png;*.tif;*.gif',...
                'Images (*.jpg,*.png,*.tif,*.gif)';}); 
            app.Imagepath1.Value=fullfile(path,file);
        end

        % Button pushed function: Browse2
        function Browse2ButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg;*.png;*.tif;*.gif',...
                'Images (*.jpg,*.png,*.tif,*.gif)';}); 
            app.Imagepath2.Value=fullfile(path,file);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AmplitudeAndPhaseSwapGUIUIFigure = uifigure('Visible', 'off');
            app.AmplitudeAndPhaseSwapGUIUIFigure.Name = 'AmplitudeAndPhaseSwap GUI';
            app.GridLayout = uigridlayout(app.AmplitudeAndPhaseSwapGUIUIFigure);
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Amplitude And Phase Swap ';
            app.Copyrights = uilabel(app.GridLayout);
            app.Copyrights.Text = 'Shay Karadi & Tamir Karamani © ';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.FullPathofImage2Label = uilabel(app.GridLayout);
            app.FullPathofImage2Label.Text = 'Full Path of Image 2';
            app.Imagepath1 = uieditfield(app.GridLayout, 'text');
            app.Imagepath1.Value = 'moon.tif';
            app.FullPathofImage1Label = uilabel(app.GridLayout);
            app.FullPathofImage1Label.Text = 'Full Path of Image 1';
            app.Imagepath2 = uieditfield(app.GridLayout, 'text');
            app.Imagepath2.Value = 'cameraman.tif';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Browse1 = uibutton(app.GridLayout, 'push');
            app.Browse1.ButtonPushedFcn = createCallbackFcn(app, @Browse1Pushed, true);
            app.Browse1.Text = 'Browse';
            app.Browse2 = uibutton(app.GridLayout, 'push');
            app.Browse2.ButtonPushedFcn = createCallbackFcn(app, @Browse2ButtonPushed, true);
            app.Browse2.Text = 'Browse';
            % Show the figure after all components are created
            app.AmplitudeAndPhaseSwapGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmplitudeAndPhaseSwapApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AmplitudeAndPhaseSwapGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AmplitudeAndPhaseSwapGUIUIFigure)
        end
    end
end