classdef MotionBlurrParameterEstimationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionBlurrParameterEstimation  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Title                           matlab.ui.control.Label
        AngleSpinnerLabel               matlab.ui.control.Label
        AngleSpinner                    matlab.ui.control.Spinner
        LengthSpinnerLabel              matlab.ui.control.Label
        LengthSpinner                   matlab.ui.control.Spinner
        choosemethodDropDown            matlab.ui.control.DropDown
        EstimateButton                  matlab.ui.control.Button
        EstimatedAngle                  matlab.ui.control.Label
        EstimatedLength                 matlab.ui.control.Label
        theta                           matlab.ui.control.Label
        Length                          matlab.ui.control.Label
        ShowButton                      matlab.ui.control.Button
        RestoreButton                   matlab.ui.control.Button
        ChooseMethod                    matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        Copyrights                      matlab.ui.control.Label
        FullPathofImageLabel            matlab.ui.control.Label
        Imagepath                       matlab.ui.control.EditField
        RadonExampleButton              matlab.ui.control.Button
        Browse                          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            motionblur(app.Imagepath.Value,app.LengthSpinner.Value,app.AngleSpinner.Value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all;
        end

        % Button pushed function: EstimateButton
        function EstimateButtonPushed(app, event)
            if app.choosemethodDropDown.Value == 'R'
                [~,Blurred]  = motionblur(app.Imagepath.Value,app.LengthSpinner.Value,app.AngleSpinner.Value,1);
                [Angle,L]=RadonEst(Blurred);
                app.theta.Text=int2str(Angle);
                app.Length.Text=int2str(L);
                app.RestoreButton.Visible='on';
                app.EstimatedLength.Visible='on';
                app.Length.Visible='on';
            elseif app.choosemethodDropDown.Value == 'C'
                [~,Blurred] = motionblur(app.Imagepath.Value,app.LengthSpinner.Value,app.AngleSpinner.Value,1);
                [Angle,L]=Cepstrum(Blurred);
                app.theta.Text=int2str(Angle);
                app.Length.Text=int2str(L);
                app.RestoreButton.Visible='on';
                app.EstimatedLength.Visible='on';
                app.Length.Visible='on';
            elseif app.choosemethodDropDown.Value == 'G'
                [~,Blurred] = motionblur(app.Imagepath.Value,app.LengthSpinner.Value,app.AngleSpinner.Value,1);
                Angle=Gabor(Blurred);
                app.theta.Text=int2str(Angle);
                app.RestoreButton.Visible='off';
                app.EstimatedLength.Visible='off';
                app.Length.Visible='off';
            end
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            [Original,Blurred] = motionblur(app.Imagepath.Value,app.LengthSpinner.Value,app.AngleSpinner.Value,1);
            weiner(Original,Blurred,[app.LengthSpinner.Value,app.AngleSpinner.Value],[str2double(app.Length.Text),str2double(app.theta.Text)])
        end

        % Button pushed function: RadonExampleButton
        function RadonExampleButtonPushed(app, event)
            RadonExample()
        end

        % Button pushed function: Browse
        function BrowseButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg;*.png;*.tif;*.gif',...
                'Images (*.jpg,*.png,*.tif,*.gif)';}); 
            app.Imagepath.Value=fullfile(path,file);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionBlurrParameterEstimation = uifigure('Visible', 'off');
            app.MotionBlurrParameterEstimation.Name = 'Motion Blurr Parameter Estimation GUI';
            app.GridLayout = uigridlayout(app.MotionBlurrParameterEstimation);
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Motion Blurr Parameter Estimation';
            app.AngleSpinnerLabel = uilabel(app.GridLayout);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.GridLayout);
            app.AngleSpinner.Step = 5;
            app.AngleSpinner.Limits = [0 180];
            app.AngleSpinner.Value = 30;
            app.LengthSpinnerLabel = uilabel(app.GridLayout);
            app.LengthSpinnerLabel.Text = 'Length';
            app.LengthSpinner = uispinner(app.GridLayout);
            app.LengthSpinner.Step = 5;
            app.LengthSpinner.Limits = [0 100];
            app.LengthSpinner.Value = 20;
            app.choosemethodDropDown = uidropdown(app.GridLayout);
            app.choosemethodDropDown.Items = {'Radon', 'Gabor', 'Cepstrume'};
            app.choosemethodDropDown.ItemsData = {'R', 'G', 'C'};
            app.choosemethodDropDown.Value = 'R';
            app.EstimateButton = uibutton(app.GridLayout, 'push');
            app.EstimateButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateButtonPushed, true);
            app.EstimateButton.Text = 'Estimate';
            app.EstimatedAngle = uilabel(app.GridLayout);
            app.EstimatedAngle.Text = 'Estimated Angle :';
            app.EstimatedLength = uilabel(app.GridLayout);
            app.EstimatedLength.Text = 'Estimated Length :';
            app.theta = uilabel(app.GridLayout);
            app.theta.Text = '';
            app.Length = uilabel(app.GridLayout);
            app.Length.Text = '';
            app.ShowButton = uibutton(app.GridLayout, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.RestoreButton = uibutton(app.GridLayout, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.ChooseMethod = uilabel(app.GridLayout);
            app.ChooseMethod.Text = 'Choose Method :';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Copyrights = uilabel(app.GridLayout);
            app.Copyrights.Text = 'Shay Karadi & Tamir Karamani © ';
            app.FullPathofImageLabel = uilabel(app.GridLayout);
            app.FullPathofImageLabel.Text = 'Full Path of Image';
            app.Imagepath = uieditfield(app.GridLayout, 'text');
            app.Imagepath.Value = 'cameraman.tif';
            app.RadonExampleButton = uibutton(app.GridLayout, 'push');
            app.RadonExampleButton.ButtonPushedFcn = createCallbackFcn(app, @RadonExampleButtonPushed, true);
            app.RadonExampleButton.Text = 'Radon Example';
            app.Browse = uibutton(app.GridLayout, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.Browse.Text = 'Browse';
            % Show the figure after all components are created
            app.MotionBlurrParameterEstimation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionBlurrParameterEstimationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionBlurrParameterEstimation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionBlurrParameterEstimation)
        end
    end
end